classdef SpectralMeasurements < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralMeasurement         matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        TabGroup2                   matlab.ui.container.TabGroup
        BTab                        matlab.ui.container.Tab
        LaserControlPanel           matlab.ui.container.Panel
        nmLabel                     matlab.ui.control.Label
        dBmLabel                    matlab.ui.control.Label
        WavelengthSpinnerLabel      matlab.ui.control.Label
        txtWavelength               matlab.ui.control.Spinner
        OutputPowerSpinnerLabel     matlab.ui.control.Label
        txtPower                    matlab.ui.control.Spinner
        btnLaserOn                  matlab.ui.control.StateButton
        ConnectionPanel             matlab.ui.container.Panel
        instrConnStat               matlab.ui.control.Lamp
        InstrumentDropDownLabel     matlab.ui.control.Label
        InstrSelect1                matlab.ui.control.DropDown
        ConnectButton               matlab.ui.control.Button
        LaserOutputLampLabel        matlab.ui.control.Label
        LaserOutputLamp             matlab.ui.control.Lamp
        PowerSensorPanel            matlab.ui.container.Panel
        pwrMeas1                    matlab.ui.control.Label
        dBmLabel_3                  matlab.ui.control.Label
        btnPowerScan                matlab.ui.control.StateButton
        AveragingTSpinnerLabel      matlab.ui.control.Label
        AveragingTSpinner           matlab.ui.control.Spinner
        RangeDropDownLabel          matlab.ui.control.Label
        RangeDropDown               matlab.ui.control.DropDown
        msLabel                     matlab.ui.control.Label
        AutoCheckBox                matlab.ui.control.CheckBox
        ScanPanel                   matlab.ui.container.Panel
        nmLabel_2                   matlab.ui.control.Label
        nmLabel_3                   matlab.ui.control.Label
        btnRunSweep                 matlab.ui.control.StateButton
        ScanStartSpinnerLabel       matlab.ui.control.Label
        spinStart                   matlab.ui.control.Spinner
        ScanStopSpinnerLabel        matlab.ui.control.Label
        spinStop                    matlab.ui.control.Spinner
        StepSizeSpinnerLabel        matlab.ui.control.Label
        spinStep                    matlab.ui.control.Spinner
        nmLabel_4                   matlab.ui.control.Label
        ScanSpeedLabel              matlab.ui.control.Label
        spinSpeed                   matlab.ui.control.Spinner
        nmsLabel                    matlab.ui.control.Label
        RangeLabel                  matlab.ui.control.Label
        RangeDropDown_Sweep         matlab.ui.control.DropDown
        Panel                       matlab.ui.container.Panel
        SwitchAKnobLabel            matlab.ui.control.Label
        SwitchAKnob                 matlab.ui.control.DiscreteKnob
        SwitchBKnobLabel            matlab.ui.control.Label
        SwitchBKnob                 matlab.ui.control.DiscreteKnob
        tools                       matlab.ui.container.Tab
        FindPeaksPanel              matlab.ui.container.Panel
        FindPeaksButton             matlab.ui.control.Button
        MinPeakHeightCheckBox       matlab.ui.control.CheckBox
        MinPeakProminenceCheckBox   matlab.ui.control.CheckBox
        MaxPeakWidthCheckBox        matlab.ui.control.CheckBox
        MinPeakWidthCheckBox        matlab.ui.control.CheckBox
        MinPeakDistanceCheckBox     matlab.ui.control.CheckBox
        ProminenceSpinner           matlab.ui.control.Spinner
        MaxWidthSpinner             matlab.ui.control.Spinner
        MinWidthSpinner             matlab.ui.control.Spinner
        DistanceSpinner             matlab.ui.control.Spinner
        HeightSpinner               matlab.ui.control.Spinner
        WidePeaksPanel              matlab.ui.container.Panel
        MinPeakHeight2CheckBox      matlab.ui.control.CheckBox
        MinPeakProminence2CheckBox  matlab.ui.control.CheckBox
        MaxPeakWidth2CheckBox       matlab.ui.control.CheckBox
        MinPeakWidth2CheckBox       matlab.ui.control.CheckBox
        MinPeakDistance2CheckBox    matlab.ui.control.CheckBox
        Prominence2Spinner          matlab.ui.control.Spinner
        MaxWidth2Spinner            matlab.ui.control.Spinner
        MinWidth2Spinner            matlab.ui.control.Spinner
        Distance2Spinner            matlab.ui.control.Spinner
        Height2Spinner              matlab.ui.control.Spinner
        dBmLabel_5                  matlab.ui.control.Label
        auLabel_2                   matlab.ui.control.Label
        nmLabel_8                   matlab.ui.control.Label
        nmLabel_9                   matlab.ui.control.Label
        nmLabel_10                  matlab.ui.control.Label
        dBmLabel_4                  matlab.ui.control.Label
        auLabel                     matlab.ui.control.Label
        nmLabel_5                   matlab.ui.control.Label
        nmLabel_6                   matlab.ui.control.Label
        nmLabel_7                   matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        logger                      matlab.ui.control.TextArea
        TabGroup                    matlab.ui.container.TabGroup
        SpectrumTab                 matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        ConfigurationTab            matlab.ui.container.Tab
        UIComponentsTable           matlab.ui.control.Table
        SaveButton                  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        connection                 
    end
    
    properties (Access = private)
        Timer % Description
        connPanel01 % Description
        LockLaser % Description
        sweepResult % Description
        scanData % Description
    end
    
    methods (Access = private)
        
        function [status, idn] = sm_connect(app,g)
            status = 0; idn = 0;
            try
                if strcmp(g.status,'open'), fclose(g); end %Force to reset the connection.
                fopen(g); 
                fprintf(g,'*IDN?');
                idn = fscanf(g);
                status = 1;
                fclose(g); 
            catch EM
                log(app,EM.message)
                
            end
        end
        
        function TimerFcn(app, src, event)
        %put on a try and add switch off
            if strcmp(app.instrConnStat.Enable,'on')
                gpib_conn = app.connPanel01.gpib;
                if strcmp(gpib_conn.status,'open') 
                    fprintf(gpib_conn,'read1:pow?');
                    val = str2num(fscanf(gpib_conn));
                    app.pwrMeas1.Text = num2str(val,'%.4f');
                end
            end
        end
        
        function laserStat = switchLaser(app,g, laserStat)
            %Check if the laser is locked and unlock in necessary
            app.LockLaser = false;
            fprintf(g,'lock?');lockStat = str2double(fscanf(g));
            if lockStat
                fprintf(g,'lock 0,1234');
                app.LockLaser = true;
                log(app,'Unlocking the laser using the default password.')
                fprintf(g,'lock?'); lockStat = str2double(fscanf(g));
                if ~lockStat, log(app,'Laser unlocked.');
                else, log(app,'Unabled to unlock the laser.');
                end
            end
                
            %Switch the laser
            fprintf(g,"outp "+laserStat);
            fprintf(g,"outp?");laserStat = str2num(fscanf(g));
                
            %Lock the laser if the initial state was locked
            if ~laserStat && app.LockLaser, fprintf(g,'lock 1,1234');end
        end
        
        function wav = setLaserWav(app,g, wav)
            %Sets the laser01 central wavelenght and returns the
            %configured value in nm

            fprintf(g,"sour0:chan1:wav " + num2str(wav,'%5.3f')+'nm');
            fprintf(g,'sour0:chan1:wav?');wav = str2num(fscanf(g))*1e9;
        end
        
        function pow = setLaserPower(app,g,pow)
            fprintf(g,"sour0:chan1:pow " + num2str(pow,'%5.0f'));
            fprintf(g,'sour0:chan1:pow?');pow = str2num(fscanf(g));
        end
        
        function results = findPeaks(app)
        
         if isempty (app.sweepResult);  log (app, "Please load data")   
             return         
         end
            %separate the data into x and y components 
            E = app.sweepResult(:,2);
            x = app.sweepResult(:,1);
            %create a distance unit for correct unit conversion
            [m] = size(x,1); %units
            R = 1e9 * (x(m)-x(1)); %nm
            Distunit = R/m; %nm/units

            ax1 = app.UIAxes;
      % param = [S = the length of resonanace cavity , windowSc =  0.1; %scale of bandwidth to analyze one resonance (FSR*windowSc), n_eff = effective index of refraction ]
            param = [1570, 15e3, 15,  0.1];
            %default values of find peaks parameters so as not to have an effect if
            %not selected 
            nullHeight = -120;
            nullProminence = 0;
            nullMaxWidth = 100;
            nullMinWidth = 0;
            nullDistance = 0;
            %checks if any of the parameters are checked before finding peaks
            f1active = app.MinPeakHeightCheckBox.Value|app.MinPeakProminenceCheckBox.Value|...
                app.MaxPeakWidthCheckBox.Value|app.MinPeakWidthCheckBox.Value | app.MinPeakDistanceCheckBox.Value;
            
            if (f1active)   %replaces the default values with inserted values 
                if app.MinPeakHeightCheckBox.Value ==1, Height = app.HeightSpinner.Value; else, Height = nullHeight; end 
                if app.MinPeakProminenceCheckBox.Value ==1, Prominence = app.ProminenceSpinner.Value; else, Prominence = nullProminence; end            
                if app.MaxPeakWidthCheckBox.Value ==1,   MaxWidth = round (app.MaxWidthSpinner.Value/Distunit); else, MaxWidth = nullMaxWidth;   end
                if app.MinPeakWidthCheckBox.Value ==1,  MinWidth = round (app.MinWidthSpinner.Value/Distunit); else, MinWidth = nullMinWidth;  end
                if app.MinPeakDistanceCheckBox.Value ==1, Distance = round (app.DistanceSpinner.Value/Distunit); else, Distance = nullDistance;   end
                
                %finds peaks based on the above parameters, returns
                %peaks heights, peak location, and widths 
                [pks,locs,W]= findpeaks(E,'MinPeakHeight',Height,'MinPeakProminence', Prominence,'MaxPeakWidth', MaxWidth, 'MinPeakWidth', MinWidth, 'MinPeakDistance', Distance,'Annotate','extents');
                
                %invokes ringResonatorfunc function that returns FSR, FWHM,
                %and other parameters regarding each peak, returns zero if
                %no peaks found 
                [O] =  ringResonatorfunc({app.sweepResult},pks,locs,param);
                   
                %plots and annotates the peaks with their locations
                plot (ax1,x,E, x(locs), pks,'V','MarkerFaceColor','red'); 
                xlabels = cellstr(num2str(x(locs)));
                wlabels = cellstr(num2str(W*Distunit));
                lbls={};
                for i =1:length(locs),lbls{i}={xlabels{i}}; end
                text(ax1,x(locs), pks,lbls);
                hold (app.UIAxes,'on');
                                
            end


            %checks if any of the parameters are checked before finding peaks
         f2active = app.MinPeakHeight2CheckBox.Value | app.MinPeakProminence2CheckBox.Value |...
                app.MaxPeakWidth2CheckBox.Value | app.MinPeakWidth2CheckBox.Value | app.MinPeakDistance2CheckBox.Value;            
           
          if (f2active)   %replaces the default values with inserted values 
                if app.MinPeakHeight2CheckBox.Value ==1, Height = app.Height2Spinner.Value; else, Height = nullHeight; end 
                if app.MinPeakProminence2CheckBox.Value ==1, Prominence = app.Prominence2Spinner.Value; else, Prominence = nullProminence; end            
                if app.MaxPeakWidth2CheckBox.Value ==1, MaxWidth = round (app.MaxWidth2Spinner.Value/Distunit); else, MaxWidth = nullMaxWidth;   end
                if app.MinPeakWidth2CheckBox.Value ==1, MinWidth = round (app.MinWidth2Spinner.Value/Distunit); else, MinWidth = nullMinWidth;  end
                if app.MinPeakDistance2CheckBox.Value ==1, Distance = round (app.Distance2Spinner.Value/Distunit); else, Distance = nullDistance;   end
                
                 [pks2,locs2,W2] = findpeaks(E,'MinPeakHeight',Height,'MinPeakProminence', Prominence,'MaxPeakWidth', MaxWidth, 'MinPeakWidth', MinWidth, 'MinPeakDistance', Distance, 'Annotate','extents') ;

                %invokes ringResonatorfunc function that returns FSR, FWHM,
                %and other parameters regarding each peak, returns zero if
                %no peaks found 
                [O2] =  ringResonatorfunc({app.sweepResult},pks2,locs2,param);
                 
                %plots and annotates the peaks with their locations
                 plot (ax1,x,E, x(locs2), pks2,'V','MarkerFaceColor','blue');
                 xlabels = cellstr(num2str(x(locs2)));
                 wlabels = cellstr(num2str(W2*Distunit));
                 lbls={};
                 for i =1:length(locs2),lbls{i}={xlabels{i}}; end
                 text(ax1,x(locs2), pks2,lbls);
          end      
             hold(app.UIAxes, 'off')
        end
    end
    
    
    methods (Access = public)
        
        function results = log(app,logText)
            oldlogText = app.logger.Value;
            maxLines = 100;
            if length(oldlogText)<maxLines
                logText = [logText; oldlogText];
            else
                logText = [logText;oldlogText(1:maxLines-1)];
            end
              app.logger.Value = logText;
        end
        
        
        function setWorkspace(app, configData)
            %Sets the updated data in the data table
            configData.Properties.RowNames = string(configData.ID);
            app.UIComponentsTable.Data = configData;
            
            %Fill the combo boxes with the instruments available in the
            %configuration table
            comboStrings = strcat(string(configData.ID),'-',configData.Name);
            comboValues = configData.ID;
            app.InstrSelect1.Items = comboStrings;
            app.InstrSelect1.ItemsData = comboValues;
            
            
            %Define the GPIB connections
            app.connection = [];
            for i =1:size(configData,1)
                c = configData(i,:);
                app.connection(i).ID = string(c.ID);
                 app.connection(i).gpib = gpib(string(c.vendor), c.Board, c.GPIB);
                app.connection(i).status = false;
            end
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Name = ["Empty"];
            %Description = [""];
            %GPIB = [0];
            %Timeout = [30];
            %Enabled = [true];
            %            t = table(Name, Description, GPIB, Timeout, Enabled);
            
            %Clear any existing instruments
            i = instrfindall;
            if  ~isempty(i), fclose(i);instrreset; end
            
            %Load configuration options
            t = load('config.mat','configData');
            configData = t.configData;
           
            app.Timer = timer('Period',0.3,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            app.Timer.TimerFcn = @app.TimerFcn;
            
            setWorkspace(app, configData)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpectralMeasurement.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {901, 901};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {418, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            instrID = string(app.InstrSelect1.Value);
            t = app.UIComponentsTable.Data;
            status = false;
            
            for connIndex = 1:length(app.connection)
                c = app.connection(connIndex);
                if strcmp(c.ID,instrID)
                    if t(instrID,:).Enabled
                        [status, idn] = sm_connect(app,c.gpib);
                        if status
                            log(app,"Connected to "+idn);
                            app.instrConnStat.Enable='on';
                            
                            switch_slot = 4;
                            fopen(c.gpib);
                            swStat = send(c.gpib,"rout"+switch_slot+":chan1?");
                            
                            app.SwitchAKnob.Value = swStat(3);
                            app.SwitchAKnob.Enable = true;
                            app.btnRunSweep.Enable = true;
                            app.btnPowerScan.Enable = true;
                            app.btnLaserOn.Enable = true;
                            c.status = true;
                            app.connPanel01 = c;    %set the connection for the 1st panel as the on e found for the selected ID.
                            
                            fclose(c.gpib);
                        else
                            log(app,"The selected instrument does not respond.")
                            app.instrConnStat.Enable = 'off';
                        end
                    else
                        log(app,"The selected instrument is not enabled in the configuration.")
                    end
                    break
                end
            end
            
            if (~status)
                log(app,"Failed to connect to the measurement system.")
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            configData = app.UIComponentsTable.Data;
            save('config.mat', 'configData','-mat');
        end

        % Value changed function: btnPowerScan
        function btnPowerScanValueChanged(app, event)
            %This function will open the COM with the instrument and leave
            %it open for increase performance, and start the timed function
            %to query for the measurements in the Power Sensor.
            value = app.btnPowerScan.Value;
            if value
                try  
                    fopen(app.connPanel01.gpib);
                    fprintf(app.connPanel01.gpib,'*CLS');
                    fprintf(app.connPanel01.gpib,'*OPC?'); 
                    opcStat = str2double(fscanf(app.connPanel01.gpib));
                    if opcStat
                        range = app.RangeDropDown.Value;
                        avgT = app.AveragingTSpinner.Value*1e-3;
                        fprintf(app.connPanel01.gpib,"sens1:pow:atim "+ num2str(avgT,'%5.2f') + 's');
                        fprintf(app.connPanel01.gpib,"sens1:pow:atim?"); avgT =  str2num(fscanf(app.connPanel01.gpib));
                        if ~app.AutoCheckBox.Value
                            fprintf(app.connPanel01.gpib,"sens1:pow:rang:auto 0");
                            fprintf(app.connPanel01.gpib,"sens1:pow:rang "+ range);
                            fprintf(app.connPanel01.gpib,"sens1:pow:rang?"); range =  str2num(fscanf(app.connPanel01.gpib));
                            log(app,"Configured sensor with averaging time: " + avgT + " and range " + num2str(range,'%5.2f') +' dBm.');
                        else
                            fprintf(app.connPanel01.gpib,"sens1:pow:rang:auto 1");
                            log(app,"Configured sensor with averaging time: "+avgT+" and automatic range.")
                        end
                        
                        start(app.Timer);
                    else
                        app.Switch.Value = false;
                        fclose(app.connPanel01.gpib);
                        log(app,"The instrument is not ready.")
                    end
                catch ME
                    stop(app.Timer);
                    app.btnPowerScan.Value = false;
                    if ~isempty(app.connPanel01.gpib), fclose(app.connPanel01.gpib);end
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
            else
                stop(app.Timer);
                fclose(app.connPanel01.gpib);
            end
        end

        % Value changed function: btnLaserOn
        function btnLaserOnValueChanged(app, event)
            value = double(app.btnLaserOn.Value);
            closeConn = false;
            try  
                if strcmp(app.connPanel01.gpib.status,'closed')
                    closeConn = true;
                    fopen(app.connPanel01.gpib);
                end
                
                %Check if the intsrument can receive commands (this could
                %be on a while to wait until the instrument is ready).
                fprintf(app.connPanel01.gpib,'*OPC?'); 
                opcStat = str2double(fscanf(app.connPanel01.gpib));
                if opcStat
                    laserStat = switchLaser(app,app.connPanel01.gpib,value);
                    if laserStat
                        dblWav = setLaserWav(app,app.connPanel01.gpib, app.txtWavelength.Value);
                        dblPow = setLaserPower(app,app.connPanel01.gpib, app.txtPower.Value);
                        
                        app.txtWavelength.Value = dblWav;
                        app.txtPower.Value= dblPow;
                    end
                    app.LaserOutputLamp.Enable = laserStat;

                else
                    app.btnLaserOn.Value = false;
                    log(app,"The instrument is not ready.")
                end
            catch ME
                app.btnLaserOn.Value = false;
                log(app,"Error: "+ME.message);
                log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
            end
            if closeConn, fclose(app.connPanel01.gpib);end


        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            value = app.AutoCheckBox.Value;
            if value
                app.RangeDropDown.Enable = false;
            else
                app.RangeDropDown.Enable = true;
            end
        end

        % Value changed function: txtWavelength
        function txtWavelengthValueChanged(app, event)
            laserStat = double(app.btnLaserOn.Value);
            if laserStat
                closeConn = false;
                try  
                    if strcmp(app.connPanel01.gpib.status,'closed')
                        closeConn = true;
                        fopen(app.connPanel01.gpib);
                    end
                    %Check if the indtrument can receive commands
                    fprintf(app.connPanel01.gpib,'*OPC?'); opcStat = str2double(fscanf(app.connPanel01.gpib));
                    if opcStat
                        dblWav = app.txtWavelength.Value;
                        if laserStat, dblWav = setLaserWav(app,app.connPanel01.gpib, dblWav); end
                        app.txtWavelength.Value = dblWav;
                    end
                catch ME
                    app.btnLaserOn.Value = false;
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(app.connPanel01.gpib);end
            end
        end

        % Close request function: SpectralMeasurement
        function SpectralMeasurementCloseRequest(app, event)
            %Make sure to close all connections and delete the objects
            for i = 1:length(app.connection)
                fclose(app.connection(i).gpib);
                delete(app.connection(i).gpib);
            end
            delete(app)
            clear app
        end

        % Value changed function: SwitchAKnob
        function SwitchAKnobValueChanged(app, event)
            if app.connPanel01.status   
                value = app.SwitchAKnob.Value;
                closeConn = false;
                try  
                    g = app.connPanel01.gpib;
                    if strcmp(g.status,'closed')
                        closeConn = true;
                        fopen(g);
                    end
                    
                    %Check if the intsrument can receive commands
                    fprintf(g,'*OPC?'); 
                    if str2double(fscanf(g))
                        fprintf(g,"rout4:chan1 A,"+value);
                        fprintf(g,"rout4:chan1?"); swStat = fscanf(g);
                        app.SwitchAKnob.Value = swStat(3);  %This works only for single switches
                        
                    else
                        log(app,"The instrument is not ready.")
                    end
                catch ME
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(g);end
            end
        end

        % Value changed function: btnRunSweep
        function btnRunSweepValueChanged(app, event)
            value = app.btnRunSweep.Value;
            if value
                stop(app.Timer);
                app.btnPowerScan.Value = 0;
                
                g = app.connPanel01.gpib;
                if app.connPanel01.status
                    try fopen(g);catch ME; end
                    send(g,'*CLS');
                    opcStat = str2double(send(g,'*OPC?')); 
                    if opcStat
                        app.btnPowerScan.Enable = 0;
                        app.btnLaserOn.Enable=0;
                        
                        app.scanData.channel =1;
                        app.scanData.power = 3;             % Power in dBm (0dBm = 1mW)  
                        app.scanData.starWav =  app.spinStart.Value*1e-9;  % start wavelength in m
                        app.scanData.stopWav =  app.spinStop.Value*1e-9;  % stop wavelength in m
                        app.scanData.step =     app.spinStep.Value*1e-9;   % Stepsize in meters
                        app.scanData.sweepSpeed = app.spinSpeed.Value*1e-9;  % Sweep speed in m/s
                        app.scanData.range = app.RangeDropDown_Sweep.Value;  % Range in dBm in steps of 10, between [-70 and 40];
                                    
                        % Prepare scan
                        [app.scanData,sweepP] = prepareScan(g,app.scanData);
                        if sweepP.stat
                            app.sweepResult = runSweep(g,app.scanData);
                            plot(app.UIAxes,app.sweepResult(:,1),app.sweepResult(:,2));
                            
                        end
                        
                        app.btnPowerScan.Enable = 1;
                        app.btnLaserOn.Enable = 1;
                        
                    end
                    fclose(g);
                end
            else
               
            end
            app.btnRunSweep.Value = 0;
            app.btnPowerScan.Enable = 1;
            app.btnLaserOn.Enable = 1;
            
        end

        % Value changed function: txtPower
        function txtPowerValueChanged(app, event)

            if app.connPanel01.status
                
                try  
                    closeConn = false;
                    if strcmp(app.connPanel01.gpib.status,'closed')
                        closeConn = true;
                        fopen(app.connPanel01.gpib);
                    end
                    %Check if the ind=strument can receive commands
                    fprintf(app.connPanel01.gpib,'*OPC?'); opcStat = str2double(fscanf(app.connPanel01.gpib));
                    if opcStat
                        dblPow = app.txtPower.Value;
                        app.txtPower.Value = setLaserPower(app,app.connPanel01.gpib, dblPow);
                    end
                catch ME
                    app.btnLaserOn.Value = false;
                    log(app,"Error: "+ME.message);
                    log(app,"Error in " + ME.stack(1).name +", in line "+ ME.stack(1).line);
                end
                if closeConn, fclose(app.connPanel01.gpib);end
            end      
        end

        % Value changed function: RangeDropDown
        function RangeDropDownValueChanged(app, event)
            value = app.RangeDropDown.Value;
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            data = app.sweepResult;
            uisave('data');
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            try
                [file,path] = uigetfile('.mat');
                if file == 0, return;end
                data = load(strcat(path,file));
                app.sweepResult = data.data;
                plot(app.UIAxes,app.sweepResult(:,1),app.sweepResult(:,2));
            catch EM
                log(app,EM.message)
            end
        end

        % Callback function
        function FilterKnobValueChanged(app, event)
            
        end

        % Callback function
        function FilterValueSpinnerValueChanging(app, event)


        end

        % Callback function
        function FindPeaksButtonPushed(app, event)
                    findPeaks(app);
        end

        % Button pushed function: FindPeaksButton
        function FindPeaksButtonPushed2(app, event)
                findPeaks(app);
        end

        % Value changed function: MinPeakHeightCheckBox
        function MinPeakHeightCheckBoxValueChanged(app, event)
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectralMeasurement = uifigure('Visible', 'off');
            app.SpectralMeasurement.Name = 'Spectral Measurements';
            app.SpectralMeasurement.CloseRequestFcn = createCallbackFcn(app, @SpectralMeasurementCloseRequest, true);
            app.SpectralMeasurement.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.SpectralMeasurement);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.GridLayout = uigridlayout(app.SpectralMeasurement);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'tandon_long_color.png';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.BTab = uitab(app.TabGroup2);
            app.BTab.Title = '8164B';
            app.LaserControlPanel = uipanel(app.BTab);
            app.LaserControlPanel.Title = 'Laser Control';
            app.nmLabel = uilabel(app.LaserControlPanel);
            app.nmLabel.Text = 'nm';
            app.dBmLabel = uilabel(app.LaserControlPanel);
            app.dBmLabel.Text = 'dBm';
            app.WavelengthSpinnerLabel = uilabel(app.LaserControlPanel);
            app.WavelengthSpinnerLabel.Text = 'Wavelength';
            app.txtWavelength = uispinner(app.LaserControlPanel);
            app.txtWavelength.Step = 0.1;
            app.txtWavelength.Limits = [1500 1640];
            app.txtWavelength.ValueDisplayFormat = '%.3f';
            app.txtWavelength.ValueChangedFcn = createCallbackFcn(app, @txtWavelengthValueChanged, true);
            app.txtWavelength.Value = 1550;
            app.OutputPowerSpinnerLabel = uilabel(app.LaserControlPanel);
            app.OutputPowerSpinnerLabel.Text = 'Output Power';
            app.txtPower = uispinner(app.LaserControlPanel);
            app.txtPower.Step = 0.1;
            app.txtPower.Limits = [0 13];
            app.txtPower.ValueDisplayFormat = '%5.2f';
            app.txtPower.ValueChangedFcn = createCallbackFcn(app, @txtPowerValueChanged, true);
            app.txtPower.Value = 3;
            app.btnLaserOn = uibutton(app.LaserControlPanel, 'state');
            app.btnLaserOn.ValueChangedFcn = createCallbackFcn(app, @btnLaserOnValueChanged, true);
            app.btnLaserOn.Enable = 'off';
            app.btnLaserOn.Text = 'On';
            app.ConnectionPanel = uipanel(app.BTab);
            app.ConnectionPanel.Title = 'Connection';
            app.instrConnStat = uilamp(app.ConnectionPanel);
            app.instrConnStat.Enable = 'off';
            app.InstrumentDropDownLabel = uilabel(app.ConnectionPanel);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrSelect1 = uidropdown(app.ConnectionPanel);
            app.InstrSelect1.ItemsData = {'1001'};
            app.InstrSelect1.Value = '1001';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LaserOutputLampLabel = uilabel(app.ConnectionPanel);
            app.LaserOutputLampLabel.Text = 'Laser Output';
            app.LaserOutputLamp = uilamp(app.ConnectionPanel);
            app.LaserOutputLamp.Enable = 'off';
            app.PowerSensorPanel = uipanel(app.BTab);
            app.PowerSensorPanel.Title = 'Power Sensor';
            app.pwrMeas1 = uilabel(app.PowerSensorPanel);
            app.pwrMeas1.Text = '0.00';
            app.dBmLabel_3 = uilabel(app.PowerSensorPanel);
            app.dBmLabel_3.Text = 'dBm';
            app.btnPowerScan = uibutton(app.PowerSensorPanel, 'state');
            app.btnPowerScan.ValueChangedFcn = createCallbackFcn(app, @btnPowerScanValueChanged, true);
            app.btnPowerScan.Enable = 'off';
            app.btnPowerScan.Text = 'Run';
            app.AveragingTSpinnerLabel = uilabel(app.PowerSensorPanel);
            app.AveragingTSpinnerLabel.Text = 'Averaging T';
            app.AveragingTSpinner = uispinner(app.PowerSensorPanel);
            app.AveragingTSpinner.Limits = [1 1000];
            app.AveragingTSpinner.ValueDisplayFormat = '%5.2f';
            app.AveragingTSpinner.Value = 200;
            app.RangeDropDownLabel = uilabel(app.PowerSensorPanel);
            app.RangeDropDownLabel.Enable = 'off';
            app.RangeDropDownLabel.Text = 'Range';
            app.RangeDropDown = uidropdown(app.PowerSensorPanel);
            app.RangeDropDown.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.RangeDropDown.ItemsData = {'40DBM', '30DBM', '20DBM', '10DBM', '0DBM', '-10DBM', '-20DBM', '-30DBM', '-40DBM', '-50DBM', '-60DBM', '-70DBM'};
            app.RangeDropDown.ValueChangedFcn = createCallbackFcn(app, @RangeDropDownValueChanged, true);
            app.RangeDropDown.Enable = 'off';
            app.RangeDropDown.Value = '-20DBM';
            app.msLabel = uilabel(app.PowerSensorPanel);
            app.msLabel.Text = 'ms';
            app.AutoCheckBox = uicheckbox(app.PowerSensorPanel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox.Value = true;
            app.ScanPanel = uipanel(app.BTab);
            app.ScanPanel.Title = 'Scan';
            app.nmLabel_2 = uilabel(app.ScanPanel);
            app.nmLabel_2.Text = 'nm';
            app.nmLabel_3 = uilabel(app.ScanPanel);
            app.nmLabel_3.Text = 'nm';
            app.btnRunSweep = uibutton(app.ScanPanel, 'state');
            app.btnRunSweep.ValueChangedFcn = createCallbackFcn(app, @btnRunSweepValueChanged, true);
            app.btnRunSweep.Enable = 'off';
            app.btnRunSweep.Text = 'Run';
            app.ScanStartSpinnerLabel = uilabel(app.ScanPanel);
            app.ScanStartSpinnerLabel.Text = 'Scan Start';
            app.spinStart = uispinner(app.ScanPanel);
            app.spinStart.Limits = [1500 1640];
            app.spinStart.Value = 1540;
            app.ScanStopSpinnerLabel = uilabel(app.ScanPanel);
            app.ScanStopSpinnerLabel.Text = 'Scan Stop';
            app.spinStop = uispinner(app.ScanPanel);
            app.spinStop.Limits = [1500 1640];
            app.spinStop.Value = 1560;
            app.StepSizeSpinnerLabel = uilabel(app.ScanPanel);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.spinStep = uispinner(app.ScanPanel);
            app.spinStep.Step = 0.01;
            app.spinStep.Limits = [0.01 1];
            app.spinStep.Value = 0.1;
            app.nmLabel_4 = uilabel(app.ScanPanel);
            app.nmLabel_4.Text = 'nm';
            app.ScanSpeedLabel = uilabel(app.ScanPanel);
            app.ScanSpeedLabel.Text = 'Scan Speed';
            app.spinSpeed = uispinner(app.ScanPanel);
            app.spinSpeed.Step = 0.1;
            app.spinSpeed.Limits = [0.1 20];
            app.spinSpeed.ValueDisplayFormat = '%2.1f';
            app.spinSpeed.Value = 10;
            app.nmsLabel = uilabel(app.ScanPanel);
            app.nmsLabel.Text = 'nm/s';
            app.RangeLabel = uilabel(app.ScanPanel);
            app.RangeLabel.Text = 'Range';
            app.RangeDropDown_Sweep = uidropdown(app.ScanPanel);
            app.RangeDropDown_Sweep.Items = {'40 dBm', '30 dBm', '20 dBm', '10 dBm', '0 dBm', '-10 dBm', '-20 dBm', '-30 dBm', '-40 dBm', '-50 dBm', '-60 dBm', '-70 dBm'};
            app.RangeDropDown_Sweep.ItemsData = {'40', '30', '20', '10', '0', '-10', '-20', '-30', '-40', '-50', '-60', '-70'};
            app.RangeDropDown_Sweep.Value = '10';
            app.Panel = uipanel(app.BTab);
            app.Panel.Title = 'Panel';
            app.SwitchAKnobLabel = uilabel(app.Panel);
            app.SwitchAKnobLabel.Enable = 'off';
            app.SwitchAKnobLabel.Text = 'Switch A';
            app.SwitchAKnob = uiknob(app.Panel, 'discrete');
            app.SwitchAKnob.Items = {'1', '2', '3', '4'};
            app.SwitchAKnob.ValueChangedFcn = createCallbackFcn(app, @SwitchAKnobValueChanged, true);
            app.SwitchAKnob.Enable = 'off';
            app.SwitchAKnob.Value = '1';
            app.SwitchBKnobLabel = uilabel(app.Panel);
            app.SwitchBKnobLabel.Enable = 'off';
            app.SwitchBKnobLabel.Text = 'Switch B';
            app.SwitchBKnob = uiknob(app.Panel, 'discrete');
            app.SwitchBKnob.Items = {'1', '2', '3', '4'};
            app.SwitchBKnob.Enable = 'off';
            app.SwitchBKnob.Value = '1';
            app.tools = uitab(app.TabGroup2);
            app.tools.Title = 'tools';
            app.FindPeaksPanel = uipanel(app.tools);
            app.FindPeaksPanel.Title = 'Find Peaks';
            app.FindPeaksButton = uibutton(app.FindPeaksPanel, 'push');
            app.FindPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindPeaksButtonPushed2, true);
            app.FindPeaksButton.Text = {'Find Peaks'; ''};
            app.MinPeakHeightCheckBox = uicheckbox(app.FindPeaksPanel);
            app.MinPeakHeightCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinPeakHeightCheckBoxValueChanged, true);
            app.MinPeakHeightCheckBox.Text = 'MinPeakHeight';
            app.MinPeakProminenceCheckBox = uicheckbox(app.FindPeaksPanel);
            app.MinPeakProminenceCheckBox.Text = 'MinPeakProminence';
            app.MaxPeakWidthCheckBox = uicheckbox(app.FindPeaksPanel);
            app.MaxPeakWidthCheckBox.Text = 'MaxPeakWidth';
            app.MinPeakWidthCheckBox = uicheckbox(app.FindPeaksPanel);
            app.MinPeakWidthCheckBox.Text = 'MinPeakWidth';
            app.MinPeakDistanceCheckBox = uicheckbox(app.FindPeaksPanel);
            app.MinPeakDistanceCheckBox.Text = 'MinPeakDistance';
            app.ProminenceSpinner = uispinner(app.FindPeaksPanel);
            app.ProminenceSpinner.Limits = [0 Inf];
            app.ProminenceSpinner.Value = 5;
            app.MaxWidthSpinner = uispinner(app.FindPeaksPanel);
            app.MaxWidthSpinner.Step = 0.1;
            app.MaxWidthSpinner.Limits = [0 Inf];
            app.MaxWidthSpinner.Value = 0.5;
            app.MinWidthSpinner = uispinner(app.FindPeaksPanel);
            app.MinWidthSpinner.Step = 0.1;
            app.MinWidthSpinner.Limits = [0 Inf];
            app.MinWidthSpinner.Value = 0.1;
            app.DistanceSpinner = uispinner(app.FindPeaksPanel);
            app.DistanceSpinner.Limits = [0 Inf];
            app.DistanceSpinner.Value = 3;
            app.HeightSpinner = uispinner(app.FindPeaksPanel);
            app.HeightSpinner.Value = -35;
            app.WidePeaksPanel = uipanel(app.FindPeaksPanel);
            app.WidePeaksPanel.Title = 'WidePeaks';
            app.MinPeakHeight2CheckBox = uicheckbox(app.WidePeaksPanel);
            app.MinPeakHeight2CheckBox.Text = 'MinPeakHeight2';
            app.MinPeakProminence2CheckBox = uicheckbox(app.WidePeaksPanel);
            app.MinPeakProminence2CheckBox.Text = 'MinPeakProminence2';
            app.MaxPeakWidth2CheckBox = uicheckbox(app.WidePeaksPanel);
            app.MaxPeakWidth2CheckBox.Text = 'MaxPeakWidth2';
            app.MinPeakWidth2CheckBox = uicheckbox(app.WidePeaksPanel);
            app.MinPeakWidth2CheckBox.Text = 'MinPeakWidth2';
            app.MinPeakDistance2CheckBox = uicheckbox(app.WidePeaksPanel);
            app.MinPeakDistance2CheckBox.Text = 'MinPeakDistance2';
            app.Prominence2Spinner = uispinner(app.WidePeaksPanel);
            app.Prominence2Spinner.Limits = [0 Inf];
            app.Prominence2Spinner.Value = 3;
            app.MaxWidth2Spinner = uispinner(app.WidePeaksPanel);
            app.MaxWidth2Spinner.Step = 0.1;
            app.MaxWidth2Spinner.Limits = [0 Inf];
            app.MaxWidth2Spinner.Value = 20;
            app.MinWidth2Spinner = uispinner(app.WidePeaksPanel);
            app.MinWidth2Spinner.Step = 0.1;
            app.MinWidth2Spinner.Limits = [0 Inf];
            app.MinWidth2Spinner.Value = 0.5;
            app.Distance2Spinner = uispinner(app.WidePeaksPanel);
            app.Distance2Spinner.Limits = [0 Inf];
            app.Distance2Spinner.Value = 3;
            app.Height2Spinner = uispinner(app.WidePeaksPanel);
            app.Height2Spinner.Value = -45;
            app.dBmLabel_5 = uilabel(app.WidePeaksPanel);
            app.dBmLabel_5.Text = 'dBm';
            app.auLabel_2 = uilabel(app.WidePeaksPanel);
            app.auLabel_2.Text = 'a.u.';
            app.nmLabel_8 = uilabel(app.WidePeaksPanel);
            app.nmLabel_8.Text = 'nm';
            app.nmLabel_9 = uilabel(app.WidePeaksPanel);
            app.nmLabel_9.Text = 'nm';
            app.nmLabel_10 = uilabel(app.WidePeaksPanel);
            app.nmLabel_10.Text = 'nm';
            app.dBmLabel_4 = uilabel(app.FindPeaksPanel);
            app.dBmLabel_4.Text = 'dBm';
            app.auLabel = uilabel(app.FindPeaksPanel);
            app.auLabel.Text = 'a.u.';
            app.nmLabel_5 = uilabel(app.FindPeaksPanel);
            app.nmLabel_5.Text = 'nm';
            app.nmLabel_6 = uilabel(app.FindPeaksPanel);
            app.nmLabel_6.Text = 'nm';
            app.nmLabel_7 = uilabel(app.FindPeaksPanel);
            app.nmLabel_7.Text = 'nm';
            app.RightPanel = uipanel(app.GridLayout);
            app.logger = uitextarea(app.RightPanel);
            app.logger.Value = {'Spectral Measurements Interface, (c) NYU PRL 2019'};
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.UIAxes = uiaxes(app.SpectrumTab);
            title(app.UIAxes, 'Spectrum')
            xlabel(app.UIAxes, 'wavelength (m)')
            ylabel(app.UIAxes, 'intensity (a.u.)')
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.UIComponentsTable = uitable(app.ConfigurationTab);
            app.UIComponentsTable.ColumnName = {'ID'; 'Name'; 'Type'; 'Description'; 'Vendor'; 'Board'; 'GPIB Port'; 'Timeout'; 'Enabled'};
            app.UIComponentsTable.RowName = {};
            app.UIComponentsTable.ColumnEditable = true;
            app.SaveButton = uibutton(app.ConfigurationTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.SpectralMeasurement.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralMeasurements

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectralMeasurement)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralMeasurement)
        end
    end
end